classdef JointControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        Joint1Slider        matlab.ui.control.Slider
        Joint2Slider        matlab.ui.control.Slider
        Joint3Slider        matlab.ui.control.Slider
        Joint4Slider        matlab.ui.control.Slider
        Joint5Slider        matlab.ui.control.Slider
        Joint6Slider        matlab.ui.control.Slider
        Joint1Gauge         matlab.ui.control.Gauge
        Joint1GaugeLabel    matlab.ui.control.Label
        Joint6Gauge         matlab.ui.control.Gauge
        Joint6Label         matlab.ui.control.Label
        Joint2Gauge         matlab.ui.control.Gauge
        Joint2Gauge_2Label  matlab.ui.control.Label
        Joint3Gauge         matlab.ui.control.Gauge
        Joint3GaugeLabel    matlab.ui.control.Label
        Joint5Gauge         matlab.ui.control.Gauge
        Joint5GaugeLabel    matlab.ui.control.Label
        Joint4Gauge         matlab.ui.control.Gauge
        Joint4GaugeLabel    matlab.ui.control.Label
        KnucklesRoboticArmJointControlLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Joint1Gauge.Value = changingValue;
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Joint2Gauge.Value = changingValue;
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Joint3Gauge.Value = changingValue;
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Joint4Gauge.Value = changingValue;
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Joint5Gauge.Value = changingValue;
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Joint6Gauge.Value = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'appdesignerInstrumentPanel.png';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [0 180];
            app.Joint1Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [0 180];
            app.Joint2Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [0 180];
            app.Joint3Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [0 180];
            app.Joint4Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [0 180];
            app.Joint5Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [0 180];
            app.Joint6Slider.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.Joint1Gauge = uigauge(app.UIFigure, 'circular');
            app.Joint1Gauge.Limits = [0 180];
            app.Joint1Gauge.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint1GaugeLabel = uilabel(app.UIFigure);
            app.Joint1GaugeLabel.Text = 'Joint 1';
            app.Joint6Gauge = uigauge(app.UIFigure, 'circular');
            app.Joint6Gauge.Limits = [0 180];
            app.Joint6Gauge.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint6Label = uilabel(app.UIFigure);
            app.Joint6Label.Text = 'Joint 6';
            app.Joint2Gauge = uigauge(app.UIFigure, 'circular');
            app.Joint2Gauge.Limits = [0 180];
            app.Joint2Gauge.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint2Gauge_2Label = uilabel(app.UIFigure);
            app.Joint2Gauge_2Label.Text = 'Joint 2';
            app.Joint3Gauge = uigauge(app.UIFigure, 'circular');
            app.Joint3Gauge.Limits = [0 180];
            app.Joint3Gauge.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint3GaugeLabel = uilabel(app.UIFigure);
            app.Joint3GaugeLabel.Text = 'Joint 3';
            app.Joint5Gauge = uigauge(app.UIFigure, 'circular');
            app.Joint5Gauge.Limits = [0 180];
            app.Joint5Gauge.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint5GaugeLabel = uilabel(app.UIFigure);
            app.Joint5GaugeLabel.Text = 'Joint 5';
            app.Joint4Gauge = uigauge(app.UIFigure, 'circular');
            app.Joint4Gauge.Limits = [0 180];
            app.Joint4Gauge.MajorTicks = [0 30 60 90 120 150 180];
            app.Joint4GaugeLabel = uilabel(app.UIFigure);
            app.Joint4GaugeLabel.Text = 'Joint 4';
            app.KnucklesRoboticArmJointControlLabel = uilabel(app.UIFigure);
            app.KnucklesRoboticArmJointControlLabel.Text = 'Knuckles Robotic Arm Joint Control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JointControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end